classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        Processar                    matlab.ui.container.Tab
        DefinirMetaButton            matlab.ui.control.Button
        ImportarFicheirosButton      matlab.ui.control.Button
        FicheirosaprocessarTextArea  matlab.ui.control.TextArea
        FicheirosaprocessarLabel     matlab.ui.control.Label
        CordenadasdaMetaLabel        matlab.ui.control.Label
        LONEditField                 matlab.ui.control.NumericEditField
        LONEditFieldLabel            matlab.ui.control.Label
        LATEditField                 matlab.ui.control.NumericEditField
        LATEditFieldLabel            matlab.ui.control.Label
        ProcessarButton              matlab.ui.control.Button
        GraficosTab                  matlab.ui.container.Tab
        GraficoDropDown              matlab.ui.control.DropDown
        GraficoDropDownLabel         matlab.ui.control.Label
        ImportFileButton             matlab.ui.control.Button
        AplicaodeTelemetriaLabel     matlab.ui.control.Label
        TrackButton                  matlab.ui.control.Button
        VoltaSpinner                 matlab.ui.control.Spinner
        VoltaSpinnerLabel            matlab.ui.control.Label
        DataInput                    matlab.ui.control.DropDown
        FileLabel                    matlab.ui.control.Label
        EixoYListBox                 matlab.ui.control.ListBox
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes_1                     matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        lap              % Variável a qual fica salvo todos os dados importados e separados por voltas
        lapNum           % Salva o numero da volta sendo analisada no momento
        dataX            % Dados que serão plotados no eixo x 
        dataY            % Dados que serão plotados no eixo y 
        dataTypeY        % Conjuntos com o tipo de dados a mostram em cada um dos graficos [['torque'],['KM/H'],['heat','gps']]
        cursorPoints     % Vector com os tempos dos pontos selecionados pelo utilizador com o rato
        interpolatedData
        file_paths       % Paths dos ficheiros que vão ser processados
        file_paths_processed
        graphs

    end
    
    methods (Access = private)
        
        function plotData(app)
% Utilizamos um loop for para podermos imprimir multiplos dados no mesmo grafico            
            for grafico = 1:3 
                for ii = 1:length(app.dataTypeY{grafico})
                        disp("grafico: " + num2str(grafico))
                        disp(app.dataTypeY{grafico})
                        disp(iscell(app.dataTypeY{grafico}))
                        if iscell(app.dataTypeY{grafico})
                            aux = app.dataTypeY{grafico}{ii};
                        else
                            disp("ii: "+ num2str(ii) + "" + app.dataTypeY{grafico})
                            aux = app.dataTypeY{grafico};
                        end
                        
                        disp(app.dataY)
                        disp(app.dataY{grafico})
                        switch aux                                        
                            case 'Speed [Km/h]'
                                app.dataY{grafico} = app.lap(app.lapNum).gps(:,3);
                                app.dataX{grafico} = app.lap(app.lapNum).gps(:,4);
                            case 'GPS'
                                app.dataY{grafico} = app.lap(app.lapNum).gps(:,1);
                                app.dataX{grafico} = app.lap(app.lapNum).gps(:,2);
                            case 'Target id [A]'
                                app.dataY{grafico} = app.lap(app.lapNum).controller.n1(:,1);
                                app.dataX{grafico} = app.lap(app.lapNum).controller.n1(:,5);
                            case 'Target iq [A]'
                                app.dataY{grafico} = app.lap(app.lapNum).controller.n1(:,2);
                                app.dataX{grafico} = app.lap(app.lapNum).controller.n1(:,5);
                            case 'id [A]'
                                app.dataY{grafico} = app.lap(app.lapNum).controller.n1(:,3);
                                app.dataX{grafico} = app.lap(app.lapNum).controller.n1(:,5);
                            case 'iq [A]'
                                app.dataY{grafico} = app.lap(app.lapNum).controller.n1(:,4);
                                app.dataX{grafico} = app.lap(app.lapNum).controller.n1(:,5);
                            case 'Ud [V]'
                                app.dataY{grafico} = app.lap(app.lapNum).controller.n2(:,1);
                                app.dataX{grafico} = app.lap(app.lapNum).controller.n2(:,5);
                            case 'Uq [V]'
                                app.dataY{grafico} = app.lap(app.lapNum).controller.n2(:,2);
                                app.dataX{grafico} = app.lap(app.lapNum).controller.n2(:,5);
                            case 'Voltage modulation'
                                app.dataY{grafico} = app.lap(app.lapNum).controller.n2(:,3);
                                app.dataX{grafico} = app.lap(app.lapNum).controller.n2(:,5);
                            case 'AC Voltage [V]'
                                app.dataY{grafico} = app.lap(app.lapNum).controller.n2(:,4);
                                app.dataX{grafico} = app.lap(app.lapNum).controller.n2(:,5);
                            case 'AC Current [A]'
                                app.dataY{grafico} = app.lap(app.lapNum).controller.n3(:,1);
                                app.dataX{grafico} = app.lap(app.lapNum).controller.n3(:,5);
                            case 'Max iq allowed [A]'
                                app.dataY{grafico} = app.lap(app.lapNum).controller.n3(:,2);
                                app.dataX{grafico} = app.lap(app.lapNum).controller.n3(:,5);
                            case 'Motor Temperature [°C]'
                                app.dataY{grafico} = app.lap(app.lapNum).controller.n3(:,3);
                                app.dataX{grafico} = app.lap(app.lapNum).controller.n3(:,5);
                            case 'Battery Voltage (Controller) [V]'
                                app.dataY{grafico} = app.lap(app.lapNum).controller.n3(:,4);
                                app.dataX{grafico} = app.lap(app.lapNum).controller.n3(:,5);
                            case 'Cap Voltage [V]'
                                app.dataY{grafico} = app.lap(app.lapNum).controller.n4(:,1);
                                app.dataX{grafico} = app.lap(app.lapNum).controller.n4(:,5);
                            case 'Heatsink Temperature [°C]'
                                app.dataY{grafico} = app.lap(app.lapNum).controller.n4(:,2);
                                app.dataX{grafico} = app.lap(app.lapNum).controller.n4(:,5);
                            case 'Battery Current (Controller) [A]'
                                app.dataY{grafico} = app.lap(app.lapNum).controller.n4(:,3);
                                app.dataX{grafico} = app.lap(app.lapNum).controller.n4(:,5);
                            case 'Torque [N.m]'
                                app.dataY{grafico} = app.lap(app.lapNum).controller.n4(:,4);
                                app.dataX{grafico} = app.lap(app.lapNum).controller.n4(:,5);
                            case 'Engine RPM'
                                app.dataY{grafico} = app.lap(app.lapNum).controller.n5(:,1);
                                app.dataX{grafico} = app.lap(app.lapNum).controller.n5(:,4); 
                            case 'Throttle Voltage [V]'
                                app.dataY{grafico} = app.lap(app.lapNum).controller.n5(:,2);
                                app.dataX{grafico} = app.lap(app.lapNum).controller.n5(:,4);
                            case 'Throttle'
                                app.dataY{grafico} = app.lap(app.lapNum).controller.n5(:,3);
                                app.dataX{grafico} = app.lap(app.lapNum).controller.n5(:,4);
                            case 'Battery Pack-1 Voltage [V]'    
                                app.dataY{grafico} = app.lap(app.lapNum).battery.assembly(:,1);
                                app.dataX{grafico} = app.lap(app.lapNum).battery.assembly(:,9);
                            case 'Battery Pack-2 Voltage [V]'    
                                app.dataY{grafico} = app.lap(app.lapNum).battery.assembly(:,2);
                                app.dataX{grafico} = app.lap(app.lapNum).battery.assembly(:,9);
                            case 'Battery Pack-3 Voltage [V]'    
                                app.dataY{grafico} = app.lap(app.lapNum).battery.assembly(:,3);
                                app.dataX{grafico} = app.lap(app.lapNum).battery.assembly(:,9);
                            case 'Battery Pack-4 Voltage [V]'    
                                app.dataY{grafico} = app.lap(app.lapNum).battery.assembly(:,4);
                                app.dataX{grafico} = app.lap(app.lapNum).battery.assembly(:,9);
                            case 'Battery Pack-5 Voltage [V]'    
                                app.dataY{grafico} = app.lap(app.lapNum).battery.assembly(:,5);
                                app.dataX{grafico} = app.lap(app.lapNum).battery.assembly(:,9);                    
                            case 'Battery Assembly Voltage [V]'
                                app.dataY{grafico} = app.lap(app.lapNum).battery.assembly(:,6);
                                app.dataX{grafico} = app.lap(app.lapNum).battery.assembly(:,9);    
                            case 'Battery maximum temperature [°C]'    
                                 app.dataY{grafico} = app.lap(app.lapNum).battery.assembly(:,7);
                                 app.dataX{grafico} = app.lap(app.lapNum).battery.assembly(:,9);
                            case 'Pack-1 V1 [V]'    
                                 app.dataY{grafico} = app.lap(app.lapNum).battery.pack1(:,1);
                                 app.dataX{grafico} = app.lap(app.lapNum).battery.pack1(:,9);     
                            case 'Pack-1 V2 [V]'    
                                 app.dataY{grafico} = app.lap(app.lapNum).battery.pack1(:,2);
                                 app.dataX{grafico} = app.lap(app.lapNum).battery.pack1(:,9);                    
                            case 'Pack-1 V3 [V]'    
                                 app.dataY{grafico} = app.lap(app.lapNum).battery.pack1(:,3);
                                 app.dataX{grafico} = app.lap(app.lapNum).battery.pack1(:,9);
                            case 'Pack-1 V4 [V]'    
                                 app.dataY{grafico} = app.lap(app.lapNum).battery.pack1(:,4);
                                 app.dataX{grafico} = app.lap(app.lapNum).battery.pack1(:,9);
                            case 'Pack-1 V5 [V]'    
                                 app.dataY{grafico} = app.lap(app.lapNum).battery.pack1(:,5);
                                 app.dataX{grafico} = app.lap(app.lapNum).battery.pack1(:,9);
                            case 'Pack-2 V1 [V]'    
                                 app.dataY{grafico} = app.lap(app.lapNum).battery.pack2(:,1);
                                 app.dataX{grafico} = app.lap(app.lapNum).battery.pack2(:,9);     
                            case 'Pack-2 V2 [V]'    
                                 app.dataY{grafico} = app.lap(app.lapNum).battery.pack2(:,2);
                                 app.dataX{grafico} = app.lap(app.lapNum).battery.pack2(:,9);                    
                            case 'Pack-2 V3 [V]'    
                                 app.dataY{grafico} = app.lap(app.lapNum).battery.pack2(:,3);
                                 app.dataX{grafico} = app.lap(app.lapNum).battery.pack2(:,9);
                            case 'Pack-2 V4 [V]'    
                                 app.dataY{grafico} = app.lap(app.lapNum).battery.pack2(:,4);
                                 app.dataX{grafico} = app.lap(app.lapNum).battery.pack2(:,9);
                            case 'Pack-2 V5 [V]'    
                                 app.dataY{grafico} = app.lap(app.lapNum).battery.pack2(:,5);
                                 app.dataX{grafico} = app.lap(app.lapNum).battery.pack2(:,9);
                            case 'Pack-3 V1 [V]'    
                                 app.dataY{grafico} = app.lap(app.lapNum).battery.pack3(:,1);
                                 app.dataX{grafico} = app.lap(app.lapNum).battery.pack3(:,9);     
                            case 'Pack-3 V2 [V]'    
                                 app.dataY{grafico} = app.lap(app.lapNum).battery.pack3(:,2);
                                 app.dataX{grafico} = app.lap(app.lapNum).battery.pack3(:,9);                    
                            case 'Pack-3 V3 [V]'    
                                 app.dataY{grafico} = app.lap(app.lapNum).battery.pack3(:,3);
                                 app.dataX{grafico} = app.lap(app.lapNum).battery.pack3(:,9);
                            case 'Pack-3 V4 [V]'    
                                 app.dataY{grafico} = app.lap(app.lapNum).battery.pack3(:,4);
                                 app.dataX{grafico} = app.lap(app.lapNum).battery.pack3(:,9);
                            case 'Pack-3 V5 [V]'    
                                 app.dataY{grafico} = app.lap(app.lapNum).battery.pack3(:,5);
                                 app.dataX{grafico} = app.lap(app.lapNum).battery.pack3(:,9);
                            case 'Pack-4 V1 [V]'    
                                 app.dataY{grafico} = app.lap(app.lapNum).battery.pack4(:,1);
                                 app.dataX{grafico} = app.lap(app.lapNum).battery.pack4(:,9);     
                            case 'Pack-4 V2 [V]'    
                                 app.dataY{grafico} = app.lap(app.lapNum).battery.pack4(:,2);
                                 app.dataX{grafico} = app.lap(app.lapNum).battery.pack4(:,9);                    
                            case 'Pack-4 V3 [V]'    
                                 app.dataY{grafico} = app.lap(app.lapNum).battery.pack4(:,3);
                                 app.dataX{grafico} = app.lap(app.lapNum).battery.pack4(:,9);
                            case 'Pack-4 V4 [V]'    
                                 app.dataY{grafico} = app.lap(app.lapNum).battery.pack4(:,4);
                                 app.dataX{grafico} = app.lap(app.lapNum).battery.pack4(:,9);
                            case 'Pack-4 V5 [V]'    
                                 app.dataY{grafico} = app.lap(app.lapNum).battery.pack4(:,5);
                                 app.dataX{grafico} = app.lap(app.lapNum).battery.pack4(:,9);
                            case 'Pack-5 V1 [V]'    
                                 app.dataY{grafico} = app.lap(app.lapNum).battery.pack5(:,1);
                                 app.dataX{grafico} = app.lap(app.lapNum).battery.pack5(:,9);     
                            case 'Pack-5 V2 [V]'    
                                 app.dataY{grafico} = app.lap(app.lapNum).battery.pack5(:,2);
                                 app.dataX{grafico} = app.lap(app.lapNum).battery.pack5(:,9);                    
                            case 'Pack-5 V3 [V]'    
                                 app.dataY{grafico} = app.lap(app.lapNum).battery.pack5(:,3);
                                 app.dataX{grafico} = app.lap(app.lapNum).battery.pack5(:,9);
                            case 'Pack-5 V4 [V]'    
                                 app.dataY{grafico} = app.lap(app.lapNum).battery.pack5(:,4);
                                 app.dataX{grafico} = app.lap(app.lapNum).battery.pack5(:,9);
                            case 'Pack-5 V5 [V]'    
                                 app.dataY{grafico} = app.lap(app.lapNum).battery.pack5(:,5);
                                 app.dataX{grafico} = app.lap(app.lapNum).battery.pack5(:,9);
                            
                        end
                        
                        plot(app.graphs{grafico},app.dataX{grafico},app.dataY{grafico},'LineWidth',1.5); 
                        xlabel(app.graphs{grafico},'Tempo(s)'); 
                        app.graphs{grafico}.FontWeight = 'bold';          
                        grid(app.graphs{grafico},'on');
                        grid(app.graphs{grafico},'minor');
                        app.graphs{grafico}.GridAlpha = 0.075;
                        
                        hold(app.graphs{grafico},'on');
                        
                        
            %             No caso de dados em falta o gráfico anterior será apagado
                        if isempty(app.dataY{grafico})
                            plot(app.graphs{grafico},[0 0],[0 0]);
                        end
                                                                      
                end
                legend(app.graphs{grafico},app.dataTypeY{grafico});
                hold(app.graphs{grafico},'off');
            end
       end  
        function  plotGPS(app)
            
            plot(app.UIAxes2,app.lap(app.lapNum).gps(:,2),app.lap(app.lapNum).gps(:,1));                          
            hold(app.UIAxes2,'on');
            set(app.UIAxes2,'XTick',[], 'YTick', []);
            xlabel(app.UIAxes2,'');
            ylabel(app.UIAxes2,'');
            title(app.UIAxes2,'GPS')

            cursorGPSpoints(app);
            plot(app.UIAxes2,app.interpolatedData(:,1),app.interpolatedData(:,2),"o","LineWidth",1.5);
            
            hold(app.UIAxes2,'off');


        end
        
        function cursorGPSpoints(app)
        % Recebe os dados da seleção com o Rato feita pelo utilizador e realiza 2
        % interpolações para obter os valores da long e lat correspondentes

            lat = app.lap(app.lapNum).gps(:,1);
            long = app.lap(app.lapNum).gps(:,2);
            gpsTime = app.lap(app.lapNum).gps(:,4);
            app.interpolatedData = zeros(length(app.cursorPoints),2);

            for ii = 1:length(app.cursorPoints)
                app.interpolatedData(ii,1) = interp1(gpsTime,long,app.cursorPoints(ii));               
            end
            for ii = 1:length(app.cursorPoints)
                app.interpolatedData(ii,2) = interp1(gpsTime,lat,app.cursorPoints(ii));               
            end
        end
    end   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          %TAB1
            app.file_paths = {};

          %TAB2
            % Varios tipos de Dados 
            for i = 1:3   
                app.dataTypeY{i} = app.EixoYListBox.Items{1};
                app.dataY{i} = {0};
            end
            app.graphs = {app.UIAxes_1,app.UIAxes_2,app.UIAxes_3};
            % Varias entradas de Data Input
            app.file_paths_processed = {};
                 
            % Varias laps
            app.VoltaSpinner.Limits = [1,1];
            app.VoltaSpinner.Value = 1;
            app.lapNum = app.VoltaSpinner.Value;        

            
        end

        % Value changed function: EixoYListBox
        function EixoYListBoxValueChanged(app, event)
% app.dataTypeY eh um string array uma vez que podem ser feitas
% multiplas selecoesde dados
            switch(app.GraficoDropDown.Value)
                case '1'
                    app.dataTypeY{1} = app.EixoYListBox.Value;
                case '2'
                    app.dataTypeY{2} = app.EixoYListBox.Value;
                case '3'
                    app.dataTypeY{3} = app.EixoYListBox.Value;
            end
            
            plotData(app);
            
        end

        % Value changed function: VoltaSpinner
        function VoltaSpinnerValueChanged(app, event)
            app.lapNum = app.VoltaSpinner.Value;
            plotData(app);
            plotGPS(app);
            
        end

        % Button pushed function: TrackButton
        function TrackButtonPushed(app, event)
% Funcao responsavel pela obtencao do ponto 'clicado' pelo utilizador 
            
% Esta função invoca o data cursor mode e cria um dcm( data cursor management). 
% Do dcm conseguimos tirar dados do cursor que em seguida são retiradas
% apenas os tempos para a realização da correspondência com o GPS.

            datacursormode(app.UIFigure,'on');
            dcm = datacursormode(app.UIFigure);
            aux = getCursorInfo(dcm);

        % Por algum motivo desconhecido não foi possivel usar vetorização.
        % Para contornar isto foi utilizado um loop for

            app.cursorPoints = zeros(length(aux),1);  % Necessário para exclusão de pontos
            
            for i = 1:length(aux)
                app.cursorPoints(i) = aux(i).Position(1);
            end

            datacursormode(app.UIFigure,'off');
            plotGPS(app);

        end

        % Value changed function: DataInput
        function DataInputChanged(app, event)
            [~,name,~] = fileparts(app.DataInput.Value);
            title(app.UIAxes_1, {name; ''},'Interpreter','none');
            load(fullfile(app.DataInput.Value),"data") ;
            %avoids segFault when changing from a file to another
            app.VoltaSpinner.Value = 1;
            app.VoltaSpinner.Limits = [1,length(data)];
            app.lapNum = app.VoltaSpinner.Value;     
            app.lap = data; 
            plotData(app);
            plotGPS(app);
            
            %enables single zoom 
            axesHandles = [app.UIAxes_1,app.UIAxes_2,app.UIAxes_3];
            linkaxes(axesHandles,'x')

            
        end

        % Button pushed function: ProcessarButton
        function ProcessarButtonPushed(app, event)
            if numel(app.file_paths) == 0
                return
            end


            %process and save data
            output_folder = fullfile(pwd, 'output'); % create a subfolder named "output" in the same directory where app is running
            if ~exist(output_folder, 'dir')
                mkdir(output_folder);
            end
            for i = 1:numel(app.file_paths)
                %data = getAllData(app.file_paths{i},[app.LATEditField.Value,app.X2EditField.Value; app.LONEditField.Value,app.Y2EditField.Value]);
                [battery,gps,susp,controller,temp] = dataProces(app.file_paths{i});
                data = getLappedData(battery,gps,susp,controller,temp, ...
                                [app.LATEditField.Value ;app.LONEditField.Value]);
                
                
                %save .mat file to
                data_struct = struct('data', data); 
                [~, name, ~] = fileparts(app.file_paths{i});
                output_file = fullfile(output_folder, [name, '_processed', '.mat']); % append '_processed' to filename
                save(output_file, '-struct', 'data_struct');
            end
            app.LATEditField.Value = 0;
            app.LONEditField.Value = 0;
        end

        % Button pushed function: ImportarFicheirosButton
        function ImportarFicheirosButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.txt', 'Select one or more files', 'MultiSelect', 'on');
            if ~isequal(filename,0) % user canceled importation
                if iscell(filename)
                    % add each of the chosen path files
                    for i = 1:numel(filename)
                        app.file_paths{end+1} = fullfile(pathname, filename{i});
                    end
                else
                    app.file_paths{end+1} = fullfile(pathname, filename);
                end
                %remove duplicates paths if chosen by user
                app.file_paths = unique(app.file_paths);
                %refreshes display showing Selected Imported Files
                app.FicheirosaprocessarTextArea.Value = strjoin(app.file_paths,newline);
            end
        end

        % Button pushed function: ImportFileButton
        function ImportFileButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.mat', 'Select one or more files', 'MultiSelect', 'on');
            if ~isequal(filename,0)
                if iscell(filename)
                    % add each of the chosen path files
                    for i = 1:numel(filename)
                        app.file_paths_processed{end+1} = fullfile(pathname, filename{i});
                    end
                else
                    app.file_paths_processed{end+1} = fullfile(pathname, filename);
                end
                %remove duplicates paths if chosen by user
                app.file_paths_processed = unique(app.file_paths_processed);
                %refreshes display showing Selected Processed Files
                file_names = {};
                    for i = 1:length(app.file_paths_processed)
                        [~,name,~] = fileparts(app.file_paths_processed{i});
                        file_names{end+1} = name;
                    end
                app.DataInput.Items = file_names;
                app.DataInput.ItemsData = app.file_paths_processed;
                app.DataInput.Value = app.DataInput.ItemsData{end};
                %signals DataInput has changed
                dummyEvent = struct('Source', app.DataInput, 'Value', app.DataInput.Value);
                DataInputChanged(app, dummyEvent); % call the function with the dummy event object
            end
        end

        % Button pushed function: DefinirMetaButton
        function DefinirMetaButtonPushed(app, event)
            function setFinishLine(~, ~)
                % Prompt the user to click on the map to select the finish line
                title('Click on the map to set the finish line');
                [app.LATEditField.Value, app.LONEditField.Value] = ginput(1);
                
                % Close the figure window and pass the finish line coordinates to the processing code
                close(gcf);
            end
            
            
            %choose finish line with GPS data from first file
            if (isempty(app.file_paths))
                disp("bazou");
                return
            end
            [~,gps,~,~,~] = dataProces(app.file_paths{1});
            lat = gps(:,1);
            lon = gps(:,2);
            %latlim = [min(lat(:)) max(lat(:))];
            %lonlim = [min(lon(:)) max(lon(:))];
            figure;
            disp("figure")
            w = worldmap('World');
            hold off;
            geoplot(lat,lon);
            geobasemap satellite;
            % Add a "Set Finish Line" button to the figure window
            uicontrol('Style', 'pushbutton', 'String', 'Set Finish Line', 'Callback', @setFinishLine);
        end

        % Value changed function: GraficoDropDown
        function GraficoDropDownValueChanged(app, event)
            graph_n = str2num(app.GraficoDropDown.Value);
            if graph_n <= 3
                app.EixoYListBox.Value = app.dataTypeY{graph_n};
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Processar = uitab(app.TabGroup);
            app.Processar.Title = 'Processar';
            app.ProcessarButton = uibutton(app.Processar, 'push');
            app.ProcessarButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessarButtonPushed, true);
            app.ProcessarButton.Text = 'Processar';
            app.LATEditFieldLabel = uilabel(app.Processar);
            app.LATEditFieldLabel.Text = 'LAT';
            app.LATEditField = uieditfield(app.Processar, 'numeric');
            app.LATEditField.ValueDisplayFormat = '%5.16g';
            app.LONEditFieldLabel = uilabel(app.Processar);
            app.LONEditFieldLabel.Text = 'LON';
            app.LONEditField = uieditfield(app.Processar, 'numeric');
            app.LONEditField.ValueDisplayFormat = '%5.16g';
            app.CordenadasdaMetaLabel = uilabel(app.Processar);
            app.CordenadasdaMetaLabel.Text = 'Cordenadas da Meta';
            app.FicheirosaprocessarLabel = uilabel(app.Processar);
            app.FicheirosaprocessarLabel.Text = 'Ficheiros  a processar:';
            app.FicheirosaprocessarTextArea = uitextarea(app.Processar);
            app.FicheirosaprocessarTextArea.Editable = 'off';
            app.ImportarFicheirosButton = uibutton(app.Processar, 'push');
            app.ImportarFicheirosButton.ButtonPushedFcn = createCallbackFcn(app, @ImportarFicheirosButtonPushed, true);
            app.ImportarFicheirosButton.Text = 'Importar Ficheiros';
            app.DefinirMetaButton = uibutton(app.Processar, 'push');
            app.DefinirMetaButton.ButtonPushedFcn = createCallbackFcn(app, @DefinirMetaButtonPushed, true);
            app.DefinirMetaButton.Text = 'Definir Meta';
            app.GraficosTab = uitab(app.TabGroup);
            app.GraficosTab.Title = 'Graficos';
            app.UIAxes2 = uiaxes(app.GraficosTab);
            title(app.UIAxes2, 'GPS')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes_1 = uiaxes(app.GraficosTab);
            title(app.UIAxes_1, 'Importe um ficheiro .mat processado primeiro')
            app.UIAxes_2 = uiaxes(app.GraficosTab);
            app.UIAxes_3 = uiaxes(app.GraficosTab);
            app.EixoYListBox = uilistbox(app.GraficosTab);
            app.EixoYListBox.Items = {'Torque [N.m]', 'Battery Assembly Voltage [V]', 'GPS', 'Speed [Km/h]', 'Target id [A]', 'Target iq [A]', 'id [A]', 'iq [A]', 'Ud [V]', 'Uq [V]', 'Voltage modulation', 'AC Voltage [V]', 'AC Current [A]', 'Max iq allowed [A]', 'Motor Temperature [°C]', 'Battery Voltage (Controller) [V]', 'Battery Current (Controller) [A]', 'Cap Voltage [V]', 'Heatsink Temperature [°C]', 'Engine RPM', 'Throttle Voltage [V]', 'Throttle', 'Battery Pack-1 Voltage [V]', 'Battery Pack-2 Voltage [V]', 'Battery Pack-3 Voltage [V]', 'Battery Pack-4 Voltage [V]', 'Battery Pack-5 Voltage [V]', 'Battery maximum temperature [°C]', 'Pack-1 V1 [V]', 'Pack-1 V2 [V]', 'Pack-1 V3 [V]', 'Pack-1 V4 [V]', 'Pack-1 V5 [V]', 'Pack-1 V6 [V]', 'Pack-2 V1 [V]', 'Pack-2 V2 [V]', 'Pack-2 V3 [V]', 'Pack-2 V4 [V]', 'Pack-2 V5 [V]', 'Pack-3 V1 [V]', 'Pack-3 V2 [V]', 'Pack-3 V3 [V]', 'Pack-3 V4 [V]', 'Pack-3 V5 [V]', 'Pack-4 V1 [V]', 'Pack-4 V2 [V]', 'Pack-4 V3 [V]', 'Pack-4 V5 [V]', 'Pack-5 V1 [V]', 'Pack-5 V2 [V]', 'Pack-5 V3 [V]', 'Pack-5 V4 [V]', 'Pack-5 V5 [V]'};
            app.EixoYListBox.Multiselect = 'on';
            app.EixoYListBox.ValueChangedFcn = createCallbackFcn(app, @EixoYListBoxValueChanged, true);
            app.EixoYListBox.Value = {'Torque [N.m]'};
            app.FileLabel = uilabel(app.GraficosTab);
            app.FileLabel.Text = 'File';
            app.DataInput = uidropdown(app.GraficosTab);
            app.DataInput.Items = {};
            app.DataInput.ValueChangedFcn = createCallbackFcn(app, @DataInputChanged, true);
            app.DataInput.Value = {};
            app.VoltaSpinnerLabel = uilabel(app.GraficosTab);
            app.VoltaSpinnerLabel.Text = 'Volta';
            app.VoltaSpinner = uispinner(app.GraficosTab);
            app.VoltaSpinner.RoundFractionalValues = 'on';
            app.VoltaSpinner.ValueChangedFcn = createCallbackFcn(app, @VoltaSpinnerValueChanged, true);
            app.VoltaSpinner.Value = 1;
            app.TrackButton = uibutton(app.GraficosTab, 'push');
            app.TrackButton.ButtonPushedFcn = createCallbackFcn(app, @TrackButtonPushed, true);
            app.TrackButton.Text = 'Track';
            app.AplicaodeTelemetriaLabel = uilabel(app.GraficosTab);
            app.AplicaodeTelemetriaLabel.Text = 'Aplicação de Telemetria';
            app.ImportFileButton = uibutton(app.GraficosTab, 'push');
            app.ImportFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportFileButtonPushed, true);
            app.ImportFileButton.Text = 'Import File';
            app.GraficoDropDownLabel = uilabel(app.GraficosTab);
            app.GraficoDropDownLabel.Text = 'Grafico:';
            app.GraficoDropDown = uidropdown(app.GraficosTab);
            app.GraficoDropDown.Items = {'1', '2', '3'};
            app.GraficoDropDown.ValueChangedFcn = createCallbackFcn(app, @GraficoDropDownValueChanged, true);
            app.GraficoDropDown.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end